classdef HORUS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        AnalisisScreen              matlab.ui.container.Panel
        AnalisisGrid                matlab.ui.container.GridLayout
        AnalisisEventosHTML         matlab.ui.control.HTML
        BackAnalisisHTML            matlab.ui.control.HTML
        ObservacionesLBL            matlab.ui.control.Label
        ObservacionesNamelbl        matlab.ui.control.Label
        ExportButton                matlab.ui.control.Button
        miradasDerechaLbl           matlab.ui.control.Label
        miradasIzquierdaLbl         matlab.ui.control.Label
        UITableResultadosDerecha    matlab.ui.control.Table
        FatigaLabel                 matlab.ui.control.Label
        PatientInfoLabel            matlab.ui.control.Label
        FATIGAname                  matlab.ui.control.Label
        DATOSDELPACIENTELabel       matlab.ui.control.Label
        Image_2                     matlab.ui.control.Image
        ANLISISPACIENTELabel        matlab.ui.control.Label
        UITableResultadosIzquierda  matlab.ui.control.Table
        GraficasScreen              matlab.ui.container.Panel
        GraficasGrid                matlab.ui.container.GridLayout
        FSLabel                     matlab.ui.control.Label
        BackGraficasHTML            matlab.ui.control.HTML
        AnalizarMedidasHTML         matlab.ui.control.HTML
        EYEL                        matlab.ui.control.Image
        EYER                        matlab.ui.control.Image
        StartBT                     matlab.ui.control.Button
        ConectedLampGrid            matlab.ui.container.GridLayout
        ConectedLamp                matlab.ui.control.Lamp
        ConectedLBL                 matlab.ui.control.Label
        VEOL                        matlab.ui.control.UIAxes
        HEOR                        matlab.ui.control.UIAxes
        HEOL                        matlab.ui.control.UIAxes
        VEOR                        matlab.ui.control.UIAxes
        EventsAnalisisScreen        matlab.ui.container.Panel
        EventsAnalisisGrid          matlab.ui.container.GridLayout
        BackEventsAnalisisHTML      matlab.ui.control.HTML
        EventsTable                 matlab.ui.control.Table
        EventsAnalisisLBL           matlab.ui.control.Label
        AnalisisSignalsScreen       matlab.ui.container.Panel
        AnalisisSignalsGrid         matlab.ui.container.GridLayout
        ResultadosAnalisisHTML      matlab.ui.control.HTML
        VerticalRExpandedHTML       matlab.ui.control.HTML
        VerticalLExpandedHTML       matlab.ui.control.HTML
        HorizontalRExpandedHTML     matlab.ui.control.HTML
        HorizontalLExpandedHTML     matlab.ui.control.HTML
        EYEL2                       matlab.ui.control.Image
        EYER2                       matlab.ui.control.Image
        RHorizontalAxis             matlab.ui.control.UIAxes
        RVerticalAxis               matlab.ui.control.UIAxes
        LHorizontalAxis             matlab.ui.control.UIAxes
        LVerticalAxis               matlab.ui.control.UIAxes
        ExpandedViewScreen          matlab.ui.container.Panel
        ExpandedViewGrid            matlab.ui.container.GridLayout
        BackExpandedViewHTML        matlab.ui.control.HTML
        ExpandedPlot                matlab.ui.control.UIAxes
        MainScreen                  matlab.ui.container.Panel
        MainGrid                    matlab.ui.container.GridLayout
        PasswordHTML                matlab.ui.control.HTML
        UserNameHTML                matlab.ui.control.HTML
        ConectionCableCheckBox      matlab.ui.control.CheckBox
        AccederHTML                 matlab.ui.control.HTML
        Hyperlink                   matlab.ui.control.Hyperlink
        SignInStateLBL              matlab.ui.control.Label
        LOGOSEPA                    matlab.ui.control.Image
        SignInLBL                   matlab.ui.control.Label
        ConectScreen                matlab.ui.container.Panel
        ConectGrid                  matlab.ui.container.GridLayout
        BackConectHTML              matlab.ui.control.HTML
        TomarMedidasHTML            matlab.ui.control.HTML
        BuscarDevicesHTML           matlab.ui.control.HTML
        LogoHorusHTML               matlab.ui.control.HTML
        DevicesListHTML             matlab.ui.control.HTML
        WifiStatusHTML              matlab.ui.control.HTML
        ShowHiddenWifisCheckBox     matlab.ui.control.CheckBox
        ChooseActionScreen          matlab.ui.container.Panel
        ChooseActionGrid            matlab.ui.container.GridLayout
        BackChooseActionHTML        matlab.ui.control.HTML
        ConectarGafasHTML           matlab.ui.control.HTML
        VerAnalisisPreviosHTML      matlab.ui.control.HTML
        Image2                      matlab.ui.control.Image
        Image                       matlab.ui.control.Image
    end


    properties (Access = private)
        usuario
        password
        WifiOff
        AvailableWifis
        DevicesWifis
        WifiInterface
        WifiTemplate
        PasswordOptions = ["zeri3333","wifi.ROS1","00000000","12345678"];
        ConnectedDevice = false;
        Device
        SHEOL
        SVEOL
        SHEOR
        SVEOR
        LineSHEOL = []
        LineSVEOL = []
        LineSHEOR = []
        LineSVEOR = []
        NoDataCount     % contador de intentos sin datos
        TimerGraficar        % timer de adquisición
        counter               % contador de muestras
        lastTime
        dtBuffer
        fs_est
        fs_fixed
        fs_calibrating = true
        fs_startTime
        FilterState
        SHEOL_f              % H EOL filtered signal (mV)
        SVEOL_f              % V EOL filtered signal (mV)
        SHEOR_f              % H EOR filtered signal (mV)
        SVEOR_f              % V EOR filtered signal (mV)
        SignalsFiltered_OK = false   % flag: true if post filtering was successful
        counter_fs = 0
        yMaxSticky = 50   % valor inicial mínimo en mV (evita escala 0 al inicio)

        % Señales globales para análisis
        HEOG_global
        VEOG_global

        % Resultados de sacadas
        SaccadesMetrics
    end

    methods (Access = private)

        function ChangeWindowTo(app,screen)
            screens = [...
                app.MainScreen,...
                app.ChooseActionScreen,...
                app.ConectScreen,...
                app.GraficasScreen,...
                app.AnalisisScreen,...
                app.EventsAnalisisScreen,...
                app.AnalisisSignalsScreen,...
                app.ExpandedViewScreen
                ];
            for a = screens
                a.Visible = 'off';
            end
            screen.Visible = 'on';
        end
        function PrepareWifi(app)
            wifiName = "";
            if ispc

                % detectar adaptador wifi (incluso deshabilitado)
                cmd = ['powershell -Command "', ...
                    'Get-NetAdapter | Where-Object {$_.NdisPhysicalMedium -eq 9} | Select-Object -First 1 -ExpandProperty Name"'];

                [~,out] = system(cmd);
                wifiName = strtrim(out);

                if wifiName == ""
                    error("No se encontró adaptador WiFi");
                end
                % comprobar estado
                [~,interfaces] = system('netsh interface show interface');

                if ~contains(interfaces,"Enabled")

                    cmd = sprintf('netsh interface set interface "%s" admin=enabled',wifiName);
                    system(cmd);

                end
                app.WifiOff = 0;
                % desconectar wifi actual
                system('netsh wlan disconnect');
            else
                app.WifiInterface = wifiName;
                error("Sistema operativo no soportado")
            end
            app.WifiInterface = wifiName;
        end
        function FindAvailableWifis(app)
            app.AvailableWifis = strings(0);
            ssids = strings(0);

            if ~ispc
                error("Sistema operativo no soportado")
            end

            % ── 1. Disable/enable para forzar rescan real (tu método original) ──
            psCommand = sprintf([...
                '$wifi = Get-NetAdapter -Name "%s"; ' ...
                'Disable-NetAdapter -Name $wifi.Name -Confirm:$false; ' ...
                'Start-Sleep -Seconds 2; ' ...
                'Enable-NetAdapter -Name $wifi.Name -Confirm:$false;'], app.WifiInterface);

            [status, out] = system(['powershell -Command "', psCommand, '"']);
            if status ~= 0
                error("Error forzando reescaneo Wi-Fi:\n%s", out);
            end

            % ── 2. Esperar a que el adaptador esté habilitado ──
            ready = false;
            for k = 1:10
                pause(1)
                [~, state] = system('netsh interface show interface');
                lines = splitlines(state);
                wifiLine = lines(contains(lines, app.WifiInterface));
                if ~isempty(wifiLine) && contains(wifiLine, ["Enabled","Habilitado"])
                    ready = true;
                    break
                end
            end

            if ~ready
                error("La interfaz WiFi no se activó correctamente")
            end

            % ── 3. Esperar scan fresco: repetir hasta que dos resultados ──
            %       consecutivos sean idénticos (descarta la caché vieja)
            pause(2);   % margen mínimo antes del primer scan

            previousSSIDs = strings(0);
            stableCount   = 0;

            for attempt = 1:12   % máx ~18 s de espera
                [status, out] = system('netsh wlan show networks mode=bssid');

                if status ~= 0
                    pause(1.5);
                    continue
                end

                % Parseo robusto (cubre ES/EN, con o sin número en SSID)
                currentSSIDs = strings(0);
                lines = splitlines(out);
                for i = 1:length(lines)
                    tok = regexp(strtrim(lines{i}), ...
                        '^SSID\s*\d*\s*:\s*(.+)$', 'tokens', 'once');
                    if ~isempty(tok)
                        ssid = strtrim(tok{1});
                        % Excluir BSSIDs (tienen ":" de dirección MAC)
                        if ~isempty(ssid) && ~contains(ssid, ':')
                            currentSSIDs(end+1) = ssid;
                        end
                    end
                end
                currentSSIDs = unique(currentSSIDs);

                % Comparar con el scan anterior
                if isequal(sort(currentSSIDs), sort(previousSSIDs)) && ~isempty(currentSSIDs)
                    stableCount = stableCount + 1;
                    if stableCount >= 2   % dos scans iguales = resultado estable
                        ssids = currentSSIDs;
                        break
                    end
                else
                    stableCount   = 0;
                    previousSSIDs = currentSSIDs;
                end

                pause(1.5);
            end

            if isempty(ssids)
                ssids = previousSSIDs;   % usar lo último que se obtuvo
            end

            ssids = unique(ssids);
            app.AvailableWifis = flip(ssids(ssids ~= ""));
        end

        function ReloadListWifis(app,listWifis)
            app.DevicesListHTML.Data = {};
            app.DevicesListHTML.Data = listWifis;
        end
        function createWifiProfile(app,ssid,password)
            system("netsh wlan delete profile name=""" + ssid + """")
            app.WifiTemplate = fileread("wifi_template.xml");

            profile = strrep(app.WifiTemplate,"SSID_AQUI",ssid);
            profile = strrep(profile,"PASSWORD_AQUI",password);

            fid = fopen("wifi_temp.xml",'w');
            fprintf(fid,profile);
            fclose(fid);

            system('netsh wlan add profile filename="wifi_temp.xml" user=current');

        end

        function DataConnection(app)
            try
                if ~isempty(app.Device)
                    app.Device = [];   % limpia la conexión anterior
                    pause(0.1);
                end
                app.Device = tcpclient("192.168.4.1",5000);
                disp('Conectado a ESP32');
                app.ConectedLamp.Color = 'green';
                app.StartBT.Enable = 'on';

            catch
                app.Device = [];  % devuelve vacío si falla
                app.ConectedLamp.Color = 'red';
                app.StartBT.Enable = 'off';
            end
        end

        function DataConnectionCable(app)
            try
                if ~isempty(app.Device)
                    app.Device = [];   % limpia la conexión anterior
                    pause(0.1);
                end
                % https://es.mathworks.com/help/matlab/import_export/read-streaming-data-from-arduino.html
                % Cerramos las conexiones serialport existentes
                delete(serialportfind);
                % Guardamos los datos del serialport en "serialObj"
                app.Device = serialport("COM5",500000);
                % Definimos la propiedad Terminator para que coincida con el terminador
                % especificado en el codigo de arduino
                configureTerminator(app.Device,"CR/LF");
                % Vaciamos serialObj para limpiar datos antiguos
                flush(app.Device);
                app.ConectedLamp.Color = 'green';
                app.StartBT.Enable = 'on';
            catch
                app.Device = [];  % devuelve vacío si falla
                uialert(app.UIFigure,'Compruebe que el Dispositivo este encendido','Ningun Dispositivo Conectado');
                app.ConectedLamp.Color = 'red';
                app.StartBT.Enable = 'off';
            end

        end
        function ChangeWifiStatus (app,state)
            app.WifiStatusHTML.Data = state;
        end
        function RefreshWifis(app)
            ChangeWifiStatus(app,'desconectado');
            app.TomarMedidasHTML.Visible = 'off';
            ReloadListWifis(app,{});
            LoadingWifis = uiprogressdlg(app.UIFigure, ...
                'Title', 'Buscando Dispositivos...', 'Message', 'Porfavor espere mientras se cargan los dispositivos...', 'Indeterminate', 'on');
            drawnow
            FindAvailableWifis(app);
            disp(app.AvailableWifis);
            if isempty(app.AvailableWifis)
                close(LoadingWifis);
                uialert(app.UIFigure,'Compruebe que el Dispositivo este encendido','Ninguna Wifi Encontrada');
            else
                %const char* ssid = "ESP32SEPA"; // NOMBRE DE LA RED A CREAR
                %const char* password = "zeri3333";   // PASSWORD DE LA RED (MINIMO 8CHAR)
                comparador = contains(app.AvailableWifis, "ESP32", "IgnoreCase", true);
                app.DevicesWifis = app.AvailableWifis(comparador);
                if isempty(app.DevicesWifis)
                    uialert(app.UIFigure,'Compruebe que el Dispositivo este encendido o busque en Redes Escondidas','Ningun Dispositivo Encontrado','Icon','warning');
                end
                ReloadListWifis(app, app.DevicesWifis);
                close(LoadingWifis);
            end
        end

        function DataAcquisition(app)
            % Reset fs state for new recording session
            app.fs_calibrating = true;
            app.fs_fixed       = [];
            app.fs_est         = 0;
            app.lastTime       = [];
            app.dtBuffer       = [];
            app.counter_fs     = 0;

            app.FilterState = struct();

            % Limpiar líneas animadas anteriores si existen
            if ~isempty(app.LineSHEOL) && isvalid(app.LineSHEOL)
                delete(app.LineSHEOL);
                delete(app.LineSVEOL);
                delete(app.LineSHEOR);
                delete(app.LineSVEOR);
            end
            app.StartBT.Text = 'STOP';
            app.StartBT.BackgroundColor = 'red';
            app.AnalizarMedidasHTML.Visible = "off";
            app.SHEOL = [];
            app.SVEOL = [];
            app.SHEOR = [];
            app.SVEOR = [];

            app.SHEOL_f = [];
            app.SVEOL_f = [];
            app.SHEOR_f = [];
            app.SVEOR_f = [];
            app.SignalsFiltered_OK = false;

            app.HEOG_global = [];
            app.VEOG_global = [];
            app.SaccadesMetrics = [];

            % Crear líneas animadas
            app.LineSHEOL = animatedline(app.HEOL,  'Color', 'r');
            app.LineSVEOL = animatedline(app.VEOL,  'Color', 'g');
            app.LineSHEOR = animatedline(app.HEOR,  'Color', 'b');
            app.LineSVEOR = animatedline(app.VEOR,  'Color', 'black');
            % YLim inicial centrado en 0, fijo
            app.yMaxSticky = 100;
            lim = [-100, 100];
            app.HEOL.YLim = lim;  app.HEOL.YLimMode = 'manual';
            app.VEOL.YLim = lim;  app.VEOL.YLimMode = 'manual';
            app.HEOR.YLim = lim;  app.HEOR.YLimMode = 'manual';
            app.VEOR.YLim = lim;  app.VEOR.YLimMode = 'manual';
            app.counter           = 0;
            app.NoDataCount = 0;
            windowSize      = 600;

            % Crear timer que ejecuta el callback cada 20ms (50Hz)
            app.TimerGraficar = timer(...
                'ExecutionMode', 'fixedRate', ...
                'Period',         0.04, ...
                'TimerFcn',       @(~,~) GraficarDatos(app, windowSize), ...
                'ErrorFcn',       @(~,~) StopAcquisition(app, 'error'));

            start(app.TimerGraficar);
        end
        function GraficarDatos(app, windowSize)
            try
                % Leer TODAS las líneas disponibles en este tick
                while app.Device.NumBytesAvailable > 0

                    data   = readline(app.Device);
                    valores = str2double(split(strtrim(data), ","));

                    if length(valores) == 4
                        app.NoDataCount = 0;
                        updateFS(app);  % mide intervalo ENTRE MUESTRAS, no entre ticks

                        s1 = valores(1);  s2 = valores(2);
                        s3 = valores(3);  s4 = valores(4);

                        S1mV = s1 * 0.80586080586;
                        S2mV = s2 * 0.80586080586;
                        S3mV = s3 * 0.80586080586;
                        S4mV = s4 * 0.80586080586;

                        if app.fs_calibrating
                            S1f = S1mV; S2f = S2mV;
                            S3f = S3mV; S4f = S4mV;
                        else
                            S1f = eogRealtimeFilter(app, S1mV, app.fs_fixed, 1);
                            S2f = eogRealtimeFilter(app, S2mV, app.fs_fixed, 2);
                            S3f = eogRealtimeFilter(app, S3mV, app.fs_fixed, 3);
                            S4f = eogRealtimeFilter(app, S4mV, app.fs_fixed, 4);
                        end
                        tickMax = max(abs([S1f, S2f, S3f, S4f]));
                        updateYLim(app, tickMax);
                        app.SHEOL(end+1) = S1mV;
                        app.SVEOL(end+1) = S2mV;
                        app.SHEOR(end+1) = S3mV;
                        app.SVEOR(end+1) = S4mV;

                        app.counter = app.counter + 1;

                        addpoints(app.LineSHEOL, app.counter, S1f)
                        addpoints(app.LineSVEOL, app.counter, S2f)
                        addpoints(app.LineSHEOR, app.counter, S3f)
                        addpoints(app.LineSVEOR, app.counter, S4f)

                        if app.counter > windowSize
                            app.HEOL.XLim = [app.counter - windowSize, app.counter];
                            app.VEOL.XLim = [app.counter - windowSize, app.counter];
                            app.HEOR.XLim = [app.counter - windowSize, app.counter];
                            app.VEOR.XLim = [app.counter - windowSize, app.counter];
                        end
                    end
                end

                % Una sola actualización visual por tick (eficiente)
                if app.counter > 0
                    app.FSLabel.Text = sprintf('FS: %.1f Hz', app.fs_est);
                    drawnow limitrate
                end

                % Timeout si no llegan datos
                app.NoDataCount = app.NoDataCount + 1;
                if app.NoDataCount > 100
                    StopAcquisition(app, 'timeout');
                end

            catch
                StopAcquisition(app, 'error');
            end
        end

        function StopAcquisition(app, reason)

            if ~isempty(app.TimerGraficar) && isvalid(app.TimerGraficar)
                stop(app.TimerGraficar);
                delete(app.TimerGraficar);
                app.TimerGraficar = [];
            end

            app.StartBT.Text            = 'START';
            app.StartBT.BackgroundColor = 'green';
            app.AnalizarMedidasHTML.Visible = "on";

            switch reason
                case 'manual'
                    if ~isempty(app.SHEOL)
                        filterSignalsPost(app);
                    end

                case 'timeout'
                    uialert(app.UIFigure, 'Device stopped sending data.', ...
                        'Connection lost', 'Icon', 'warning');
                    app.ConectedLamp.Color = 'red';
                    app.Device = [];

                case 'error'
                    uialert(app.UIFigure, 'Error reading data from device.', ...
                        'Error', 'Icon', 'error');
                    app.ConectedLamp.Color = 'red';
                    app.Device = [];
            end
        end

        function TomarMedidasClicked(app)
            ChangeWindowTo(app,app.GraficasScreen);
            value = app.ConectionCableCheckBox.Value;
            if value == 1
                DataConnectionCable(app);
                disp(app.Device);
            else
                DataConnection(app);
                disp(app.Device);
            end
        end

        function AccederClicked(app)
            app.usuario = app.UserNameHTML.Data.value;
            app.password = app.PasswordHTML.Data.value;
            disp(app.usuario);
            disp(app.password);
            app.UserNameHTML.Data = struct('clear', true);
            app.PasswordHTML.Data = struct('clear', true);
            ChangeWindowTo(app,app.ChooseActionScreen);
        end

        function ConectarGafasClicked(app)
            value = app.ConectionCableCheckBox.Value;
            if value == 1
                TomarMedidasClicked(app);
            else
                try
                    PrepareWifi(app);
                catch ME
                    uialert(app.UIFigure, ME.message, "Error WiFi");
                end
                ChangeWindowTo(app,app.ConectScreen);
                RefreshWifis(app);
            end
        end

        function VerAnalisisPreviosClicked(app)
            app.VerAnalisisPreviosHTML;
        end

        function AnalizarMedidasClicked(app)
            ChangeWindowTo(app,app.AnalisisSignalsScreen);
            PlotSignalsAcquired(app);
            calcularMetricasSacadas(app);
        end

        function ResultadosAnalisisClicked(app)
             if isempty(app.SaccadesMetrics)
                calcularMetricasSacadas(app);
            end

            ChangeWindowTo(app,app.AnalisisScreen);
        end

        function BackConectClicked(app)
            ChangeWindowTo(app,app.ChooseActionScreen);
        end

        function BackChooseActionClicked(app)
            ChangeWindowTo(app,app.MainScreen);
        end

        function BackAnalisisClicked(app)
            ChangeWindowTo(app,app.AnalisisSignalsScreen);
        end

        function BackGraficasClicked(app)
            selection = uiconfirm(app.UIFigure, ...
                'Se va a desconectar del dispositivo actual ¿Quiere seguir con la operación?', ...
                'Confirmar Desconexión', ...
                'Options',       {'OK', 'Cancel'}, ...
                'DefaultOption', 'OK', ...
                'CancelOption',  'Cancel', ...
                'Icon',          'warning');
            switch selection
                case 'OK'
                    ChangeWindowTo(app,app.ChooseActionScreen);
                case 'Cancel'
                    return
            end
        end
        function BackExpandedViewClicked(app)
            ChangeWindowTo(app,app.AnalisisSignalsScreen);
        end

        function PlotSignalsAcquired(app)
            if ~app.SignalsFiltered_OK
                return
            end
            fs = app.fs_fixed;
            N  = length(app.SHEOR_f);
            t  = (0:N-1) / fs;

            plot(app.RHorizontalAxis, t, app.SHEOR_f, 'b');
            xlabel(app.RHorizontalAxis, 'Time (s)'); ylabel(app.RHorizontalAxis, 'mV');

            plot(app.RVerticalAxis,    t, app.SVEOR_f, 'r');
            xlabel(app.RVerticalAxis,  'Time (s)'); ylabel(app.RVerticalAxis, 'mV');

            plot(app.LHorizontalAxis,  t, app.SHEOL_f, 'b');
            xlabel(app.LHorizontalAxis,'Time (s)'); ylabel(app.LHorizontalAxis, 'mV');

            plot(app.LVerticalAxis,    t, app.SVEOL_f, 'r');
            xlabel(app.LVerticalAxis,  'Time (s)'); ylabel(app.LVerticalAxis, 'mV');
        end

        function y = eogRealtimeFilter(app, x, fs, ch)

            key = sprintf('ch%d', ch);

            % Inicializar canal si no existe o está vacío
            if isempty(app.FilterState) || ~isfield(app.FilterState, key)
                [b, a] = butter(2, 10/(fs/2), 'low');
                z = zeros(max(length(a), length(b)) - 1, 1);
                buffer = zeros(round(fs * 2), 1);
                smoothBuffer = zeros(5, 1);

                app.FilterState.(key) = struct( ...
                    'b', b, 'a', a, 'z', z, ...
                    'buffer', buffer, 'idx', 1, ...
                    'smoothBuffer', smoothBuffer);
            end

            s = app.FilterState.(key);

            % Baseline
            s.buffer(s.idx) = x;
            baseline = mean(s.buffer);
            x_hp = x - baseline;

            % Filtro paso bajo
            [x_lp, s.z] = filter(s.b, s.a, x_hp, s.z);

            % Suavizado
            s.smoothBuffer = [s.smoothBuffer(2:end); x_lp];
            y = mean(s.smoothBuffer);

            % Avanzar índice circular
            s.idx = s.idx + 1;
            if s.idx > length(s.buffer)
                s.idx = 1;
            end

            app.FilterState.(key) = s;
        end

        function updateFS(app)
            % Inicialización
            if isempty(app.lastTime)
                app.lastTime       = tic;
                app.fs_est         = 0;
                app.fs_startTime   = tic;
                app.counter_fs     = 0;  % necesitas añadir esta propiedad
                return;
            end

            app.counter_fs = app.counter_fs + 1;
            elapsed = toc(app.lastTime);

            % Actualizar estimación cada 0.5 segundos
            if elapsed >= 0.5
                app.fs_est   = app.counter_fs / elapsed;
                app.lastTime = tic;
                app.counter_fs = 0;
            end

            % Fijar fs tras 2 segundos de calibración
            if app.fs_calibrating
                if toc(app.fs_startTime) > 2
                    app.fs_fixed      = round(app.fs_est);
                    app.fs_calibrating = false;
                    fprintf('FS FIJADO: %.2f Hz\n', app.fs_fixed);
                end
            end
        end

        function ExpandSignalView(app, signalName)
            app.ExpandedPlot.Title.String = signalName;

            if ~app.SignalsFiltered_OK
                ChangeWindowTo(app, app.ExpandedViewScreen);
                return
            end

            fs = app.fs_fixed;
            N  = length(app.SHEOR_f);
            t  = (0:N-1) / fs;

            switch signalName
                case 'R Vertical +'
                    y = app.SVEOR_f;  color = 'r';
                case 'R Horizontal +'
                    y = app.SHEOR_f;  color = 'b';
                case 'L Vertical +'
                    y = app.SVEOL_f;  color = 'r';
                case 'L Horizontal +'
                    y = app.SHEOL_f;  color = 'b';
                otherwise
                    ChangeWindowTo(app, app.ExpandedViewScreen);
                    return
            end

            cla(app.ExpandedPlot);
            plot(app.ExpandedPlot, t, y, color);
            xlabel(app.ExpandedPlot, 'Time (s)');
            ylabel(app.ExpandedPlot, 'mV');

            ChangeWindowTo(app, app.ExpandedViewScreen);
        end

        function AnalisisEventosClicked(app)
            if isempty(app.SaccadesMetrics)
                calcularMetricasSacadas(app);
            end
            ChangeWindowTo(app,app.EventsAnalisisScreen);
        end

        function BackEventsAnalisisClicked(app)
            ChangeWindowTo(app,app.AnalisisScreen);
        end


        function filterSignalsPost(app)

            app.SignalsFiltered_OK = false;

            % ----------------------------------------------------------------
            % 1. VALIDACIÓN
            % ----------------------------------------------------------------
            raw_signals = {app.SHEOL, app.SVEOL, app.SHEOR, app.SVEOR};
            ch_names    = {'SHEOL', 'SVEOL', 'SHEOR', 'SVEOR'};

            for k = 1:4
                if isempty(raw_signals{k})
                    warning('filterSignalsPost: %s vacío. Abortando.', ch_names{k});
                    return
                end
            end

            if app.fs_calibrating || isempty(app.fs_fixed) || app.fs_fixed <= 0
                warning('filterSignalsPost: fs_fixed no disponible. Abortando.');
                return
            end

            fs  = app.fs_fixed;
            nyq = fs / 2;

            N_min = min(cellfun(@length, raw_signals));
            fprintf('\n=== POST-ACQUISITION FILTER (HORUS) ===\n');
            fprintf('  fs_fixed : %d Hz\n', fs);
            fprintf('  Muestras : %d por canal  (%.2f s)\n', N_min, N_min / fs);

            % ----------------------------------------------------------------
            % 2. DISEÑO DE FILTROS
            % ----------------------------------------------------------------

            % -- HP: 0.5 Hz (elimina deriva lenta de electrodos y movimiento)
            %    0.05 Hz era demasiado bajo — no eliminaba la deriva visible
            fc_hp    = 0.5;
            order_hp = 2;
            Wn_hp    = fc_hp / nyq;
            if Wn_hp >= 1
                fc_hp = 0.3;
                Wn_hp = fc_hp / nyq;
            end
            [b_hp, a_hp] = butter(order_hp, Wn_hp, 'high');

            % -- Notch: 50 Hz (red eléctrica española)
            %    Solo si fs lo permite
            notch_filters = {};
            notch_freqs   = [50, 100];
            notch_valid   = notch_freqs(notch_freqs < nyq - 2);
            for k = 1:length(notch_valid)
                fn  = notch_valid(k);
                wo  = fn / nyq;
                bw  = wo / 35;
                [b_n, a_n] = iirnotch(wo, bw);
                notch_filters{end+1} = {b_n, a_n};
                fprintf('  Notch    : %.0f Hz\n', fn);
            end

            % -- LP: 25 Hz (banda útil EOG; elimina EMG y ruido ADC)
            %    Bajamos de 30 a 25 Hz para mayor limpieza sin perder sacadas
            fc_lp    = min(25, nyq - 2);
            order_lp = 4;
            [b_lp, a_lp] = butter(order_lp, fc_lp / nyq, 'low');
            if any(abs(roots(a_lp)) >= 1)
                order_lp = 2;
                [b_lp, a_lp] = butter(order_lp, fc_lp / nyq, 'low');
            end

            fprintf('  Highpass : Butterworth %d ord  |  fc = %.2f Hz\n', order_hp, fc_hp);
            fprintf('  Lowpass  : Butterworth %d ord  |  fc = %.1f Hz\n',  order_lp, fc_lp);

            % ----------------------------------------------------------------
            % 3. PIPELINE POR CANAL
            %    A: Quitar DC con median (robusto a artefactos)
            %    B: filtfilt HP  -> elimina deriva lenta
            %    C: filtfilt Notch(s)
            %    D: filtfilt LP
            %    E: Detrend adicional por segmentos (elimina deriva residual
            %       causada por movimiento lento de electrodos)
            % ----------------------------------------------------------------
            filtered_signals = cell(4,1);

            for k = 1:4
                sig = double(raw_signals{k}(:));

                % A: DC removal (el AD8232 centra en VCC/2 ~1650 mV)
                %    Si la señal ya viene centrada en 0 (filtro realtime la
                %    centró), median ≈ 0 y esto no hace daño.
                sig = sig - median(sig);

                % B: High-pass — elimina deriva de electrodos y respiración
                sig = filtfilt(b_hp, a_hp, sig);

                % C: Notch(s)
                for n = 1:length(notch_filters)
                    sig = filtfilt(notch_filters{n}{1}, notch_filters{n}{2}, sig);
                end

                % D: Low-pass
                sig = filtfilt(b_lp, a_lp, sig);

                % E: Detrend por segmentos de 5 s
                %    Elimina la deriva lenta residual que el HP a 0.5 Hz
                %    no alcanza a quitar en señales con movimientos muy lentos
                seg_len = round(fs * 5);
                sig     = detrend_segments(app,sig, seg_len);

                filtered_signals{k} = sig;

                fprintf('  [%s]  RMS=%.2f mV  |  max=%.0f mV\n', ...
                    ch_names{k}, rms(sig), max(abs(sig)));
            end

            % ----------------------------------------------------------------
            % 4. GUARDAR
            % ----------------------------------------------------------------
            app.SHEOL_f = filtered_signals{1};
            app.SVEOL_f = filtered_signals{2};
            app.SHEOR_f = filtered_signals{3};
            app.SVEOR_f = filtered_signals{4};

            app.SignalsFiltered_OK = true;
            fprintf('=========================================\n');
            fprintf('  Filtrado completo. Listo para análisis.\n\n');
        end

        function saved = saveSignals(app)
            saved = false;

            if isempty(app.SHEOL) && ~app.SignalsFiltered_OK
                uialert(app.UIFigure, 'No signals to save.', 'Save', 'Icon', 'warning');
                return
            end

            [file, path] = uiputfile('*.mat', 'Save EOG signals', ...
                sprintf('EOG_%s.mat', datestr(now, 'yyyymmdd_HHMMSS')));

            if isequal(file, 0)
                return  % user cancelled -> saved stays false
            end

            EOG        = struct();
            EOG.fs     = app.fs_fixed;
            EOG.date   = datestr(now);
            EOG.raw.SHEOL = app.SHEOL;
            EOG.raw.SVEOL = app.SVEOL;
            EOG.raw.SHEOR = app.SHEOR;
            EOG.raw.SVEOR = app.SVEOR;

            if app.SignalsFiltered_OK
                EOG.filtered.SHEOL_f = app.SHEOL_f;
                EOG.filtered.SVEOL_f = app.SVEOL_f;
                EOG.filtered.SHEOR_f = app.SHEOR_f;
                EOG.filtered.SVEOR_f = app.SVEOR_f;
            end

            save(fullfile(path, file), 'EOG');
            uialert(app.UIFigure, sprintf('Saved to:\n%s', fullfile(path, file)), ...
                'Saved', 'Icon', 'success');
            saved = true;
        end

        function y = detrend_segments(app,sig, seg_len)
            % Detrend lineal por segmentos solapados de seg_len muestras.
            % Elimina deriva lenta residual sin distorsionar sacadas.
            N = length(sig);
            y = sig;
            idx = 1;
            while idx <= N
                i_end = min(idx + seg_len - 1, N);
                y(idx:i_end) = detrend(sig(idx:i_end));
                idx = idx + seg_len;
            end
        end

        function updateYLim(app, newValue)
            absVal = abs(newValue);
            if absVal > app.yMaxSticky
                % Añadir 20% de margen y redondear para no reescalar en cada sample
                app.yMaxSticky = ceil(absVal * 1.2 / 50) * 50;
                lim = [-app.yMaxSticky, app.yMaxSticky];
                app.HEOL.YLim = lim;
                app.VEOL.YLim = lim;
                app.HEOR.YLim = lim;
                app.VEOR.YLim = lim;
            end
        end

                function calcularMetricasSacadas(app)
            % CALCULARMETRICASSACADAS
            % Usa las señales ya filtradas para construir una señal horizontal
            % global y calcular métricas básicas de sacadas.

            if ~app.SignalsFiltered_OK
                uialert(app.UIFigure, ...
                    'Primero debes adquirir y filtrar señales.', ...
                    'Sin señales filtradas');
                return
            end

            if isempty(app.fs_fixed) || app.fs_fixed <= 0
                uialert(app.UIFigure, ...
                    'No hay una frecuencia de muestreo válida.', ...
                    'Error fs');
                return
            end

            % Construcción de señales globales
           
            % Horizontal global
            % Si derecha/izquierda te queda invertido, cambia el signo:
            % app.HEOG_global = (app.SHEOL_f - app.SHEOR_f) / 2;
            app.HEOG_global = (app.SHEOR_f - app.SHEOL_f) / 2;

            % Vertical global (por ahora no se usa en métricas, pero se guarda)
            app.VEOG_global = (app.SVEOR_f + app.SVEOL_f) / 2;

            % Extraer métricas
            app.SaccadesMetrics = app.detectarSacadasEnHEOG(app.HEOG_global, app.fs_fixed);

            % Actualizar tablas y pantallas
            app.actualizarResultadosSacadas();
            app.actualizarTablaEventosSacadas();
                end

                   function metrics = detectarSacadasEnHEOG(app, sig, fs)
            % DETECTARSACADASENHEOG
            % Calcula:
            %   - duración de cada sacada
            %   - amplitud
            %   - frecuencia
            %   - velocidad relativa
            %   - número izquierda/derecha
            %   - asimetría izquierda/derecha

            sig = sig(:);
            t = (0:length(sig)-1)' / fs;

            % Velocidad relativa
            vel = [0; diff(sig)] * fs;
            velAbs = abs(vel);

            % Umbral robusto
            madVel = median(abs(velAbs - median(velAbs)));
            thVel = 4 * madVel;

            if thVel == 0 || isnan(thVel)
                thVel = 2 * std(velAbs);
            end

            if thVel == 0 || isnan(thVel)
                thVel = eps;
            end
            % Regiones candidatas a sacada
            isSacc = velAbs > thVel;

            d = diff([0; isSacc; 0]);
            idxIni = find(d == 1);
            idxFin = find(d == -1) - 1;

            % Filtrar por duración razonable
            durMin = round(0.02 * fs);   % 20 ms
            durMax = round(0.20 * fs);   % 200 ms

            durMuestras = idxFin - idxIni + 1;
            keep = (durMuestras >= durMin) & (durMuestras <= durMax);

            idxIni = idxIni(keep);
            idxFin = idxFin(keep);
            % Extraer características por sacada
        
            nSac = length(idxIni);

            onsetIdx   = zeros(nSac,1);
            offsetIdx  = zeros(nSac,1);
            peakIdx    = zeros(nSac,1);

            onsetTime  = zeros(nSac,1);
            offsetTime = zeros(nSac,1);
            peakTime   = zeros(nSac,1);

            duration   = zeros(nSac,1);
            amplitude  = zeros(nSac,1);
            peakVel    = zeros(nSac,1);
            direction  = strings(nSac,1);

            for k = 1:nSac
                i1 = idxIni(k);
                i2 = idxFin(k);

                seg = sig(i1:i2);
                segVel = vel(i1:i2);

                [~, localPeak] = max(abs(segVel));
                pk = i1 + localPeak - 1;

                onsetIdx(k)   = i1;
                offsetIdx(k)  = i2;
                peakIdx(k)    = pk;

                onsetTime(k)  = t(i1);
                offsetTime(k) = t(i2);
                peakTime(k)   = t(pk);

                duration(k)  = (i2 - i1 + 1) / fs;
                amplitude(k) = sig(i2) - sig(i1);
                peakVel(k)   = vel(pk);

                if amplitude(k) > 0
                    direction(k) = "derecha";
                else
                    direction(k) = "izquierda";
                end
            end

            % Métricas globales
            totalTime = length(sig) / fs;

            frequency_total = nSac / totalTime;      % sacadas/seg
            frequency_min   = frequency_total * 60;  % sacadas/min

            idxRight = direction == "derecha";
            idxLeft  = direction == "izquierda";

            nRight = sum(idxRight);
            nLeft  = sum(idxLeft);

            meanVelAbs   = mean(abs(peakVel), 'omitnan');
            meanVelRight = mean(abs(peakVel(idxRight)), 'omitnan');
            meanVelLeft  = mean(abs(peakVel(idxLeft)), 'omitnan');

            if isnan(meanVelRight)
                meanVelRight = 0;
            end
            if isnan(meanVelLeft)
                meanVelLeft = 0;
            end

            asymmetry_num = (nRight - nLeft) / max(nRight + nLeft, 1);

            % Tabla de eventos
            eventsTable = table(onsetIdx, offsetIdx, peakIdx, ...
                                onsetTime, offsetTime, peakTime, ...
                                duration, amplitude, peakVel, direction);

            % Guardar resultados
            metrics = struct();
            metrics.eventsTable      = eventsTable;

            metrics.duration         = duration;
            metrics.amplitude        = amplitude;
            metrics.peakVel          = peakVel;
            metrics.direction        = direction;

            metrics.num_total        = nSac;
            metrics.num_right        = nRight;
            metrics.num_left         = nLeft;

            metrics.frequency_total  = frequency_total;
            metrics.frequency_min    = frequency_min;

            metrics.mean_velocity    = meanVelAbs;
            metrics.mean_velocity_r  = meanVelRight;
            metrics.mean_velocity_l  = meanVelLeft;

            metrics.asymmetry_num    = asymmetry_num;
            metrics.threshold_vel    = thVel;
                   end

                   function actualizarResultadosSacadas(app)
            if isempty(app.SaccadesMetrics)
                return
            end

            m = app.SaccadesMetrics;
            T = m.eventsTable;

            % Clasificación simple leve/fuerte según velocidad pico
            if isempty(T)
                strongTh = 0;
            else
                strongTh = median(abs(T.peakVel));
            end

            idxLeft  = strcmp(string(T.direction), "izquierda");
            idxRight = strcmp(string(T.direction), "derecha");

            levesIzq   = sum(idxLeft  & abs(T.peakVel) <  strongTh);
            fuertesIzq = sum(idxLeft  & abs(T.peakVel) >= strongTh);

            levesDer   = sum(idxRight & abs(T.peakVel) <  strongTh);
            fuertesDer = sum(idxRight & abs(T.peakVel) >= strongTh);

            % ============================================================
            % Tablas izquierda / derecha
            % ============================================================
            app.UITableResultadosIzquierda.Data = {
                'Sacadas izquierda', m.num_left, levesIzq, fuertesIzq
                };

            app.UITableResultadosDerecha.Data = {
                'Sacadas derecha', m.num_right, levesDer, fuertesDer
                };

            % ============================================================
            % Info del paciente / sesión
            % ============================================================
            app.PatientInfoLabel.Text = sprintf([ ...
                'Usuario: %s\n' ...
                'fs: %.1f Hz\n' ...
                'Sacadas totales: %d\n' ...
                'Frecuencia: %.2f sacadas/min'], ...
                string(app.usuario), app.fs_fixed, m.num_total, m.frequency_min);

            % ============================================================
            % Fatiga (de momento no calculada)
            % ============================================================
            app.FatigaLabel.Text = 'No calculada';

            % ============================================================
            % Observaciones
            % ============================================================
            if isempty(m.duration)
                durMean = 0;
                ampMean = 0;
            else
                durMean = mean(m.duration);
                ampMean = mean(abs(m.amplitude));
            end

            obs = {};
            obs{end+1} = sprintf('Duración media: %.3f s', durMean);
            obs{end+1} = sprintf('Amplitud media: %.3f mV', ampMean);
            obs{end+1} = sprintf('Velocidad media relativa: %.3f mV/s', m.mean_velocity);
            obs{end+1} = sprintf('Sacadas derecha: %d', m.num_right);
            obs{end+1} = sprintf('Sacadas izquierda: %d', m.num_left);
            obs{end+1} = sprintf('Asimetría izquierda/derecha: %.3f', m.asymmetry_num);

            if m.asymmetry_num > 0.2
                obs{end+1} = 'Predominio de sacadas hacia la derecha.';
            elseif m.asymmetry_num < -0.2
                obs{end+1} = 'Predominio de sacadas hacia la izquierda.';
            else
                obs{end+1} = 'Distribución de sacadas relativamente equilibrada.';
            end

            app.ObservacionesLBL.Text = sprintf('%s\n', obs{:});
                   end

                   function actualizarTablaEventosSacadas(app)
            if isempty(app.SaccadesMetrics) || isempty(app.SaccadesMetrics.eventsTable)
                app.EventsTable.Data = table();
                return
            end

            T = app.SaccadesMetrics.eventsTable;

            T.onsetTime  = round(T.onsetTime, 3);
            T.offsetTime = round(T.offsetTime, 3);
            T.peakTime   = round(T.peakTime, 3);
            T.duration   = round(T.duration, 3);
            T.amplitude  = round(T.amplitude, 3);
            T.peakVel    = round(T.peakVel, 3);

            app.EventsTable.Data = T;
                   end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AccederHTML.Visible = 'off';
            screenSize = get(0, 'ScreenSize');   % [left bottom width height
            % Get app window size
            figWidth  = app.UIFigure.Position(3);
            figHeight = app.UIFigure.Position(4);
            % Compute centered position
            posX = (screenSize(3) - figWidth) / 2;
            posY = (screenSize(4) - figHeight) / 2;
            % Update UIFigure position
            app.UIFigure.Position(1) = posX;
            app.UIFigure.Position(2) = posY;

            app.BackChooseActionHTML.HTMLSource = "GoBack.html";
            app.BackChooseActionHTML.Data = struct('id', 'ChooseActionScreen');
            app.BackConectHTML.HTMLSource = "GoBack.html";
            app.BackConectHTML.Data = struct('id', 'ConectScreen');
            app.BackGraficasHTML.HTMLSource = "GoBack.html";
            app.BackGraficasHTML.Data = struct('id', 'GraficasScreen');
            app.BackExpandedViewHTML.HTMLSource = "GoBack.html";
            app.BackExpandedViewHTML.Data = struct('id', 'ExpandedViewScreen');
            app.BackAnalisisHTML.HTMLSource = "GoBack.html";
            app.BackAnalisisHTML.Data = struct('id', 'AnalisisScreen');
            app.BackEventsAnalisisHTML.HTMLSource = "GoBack.html";
            app.BackEventsAnalisisHTML.Data = struct('id', 'EventsAnalisisScreen');

            app.PasswordHTML.HTMLSource = 'TextField.html';
            app.PasswordHTML.Data = struct('type', 'password', 'label', 'Contraseña');
            app.UserNameHTML.HTMLSource = 'TextField.html';
            app.UserNameHTML.Data = struct('type', 'text', 'label', 'Usuario');

            app.WifiStatusHTML.HTMLSource= 'wifi_status.html';
            app.DevicesListHTML.HTMLSource = 'device_list.html';
            app.LogoHorusHTML.HTMLSource = 'logo_horus.html';
            app.LogoHorusHTML.Data = struct('color', '#808080');
            app.BuscarDevicesHTML.HTMLSource = 'buscar_dispositivo_button.html';

            buttons = [...
                app.TomarMedidasHTML,...
                app.AccederHTML,...
                app.ConectarGafasHTML,...
                app.VerAnalisisPreviosHTML,...
                app.AnalizarMedidasHTML,...
                app.ResultadosAnalisisHTML,...
                app.VerticalLExpandedHTML,...
                app.VerticalRExpandedHTML,...
                app.HorizontalLExpandedHTML,...
                app.HorizontalRExpandedHTML,...
                app.AnalisisEventosHTML
                ];
            for b = buttons
                b.HTMLSource = 'Button.html';
            end
            app.AccederHTML.Data = struct( ...
                'bgColor',      '#afeeee', ...
                'textColor',    '#ffffff', ...
                'borderColor',  '#ecffff', ...
                'borderWidth',  '2px',     ...
                'borderRadius', '26px',    ...
                'label',        'Acceder' ...
                );
            app.AnalizarMedidasHTML.Data = struct( ...
                'bgColor',      '#a0c4ff', ...
                'textColor',    '#4b0082', ...
                'borderColor',  '#a0c4ff', ...
                'borderWidth',  '0',     ...
                'borderRadius', '14px',    ...
                'label',        'Analizar Medidas' ...
                );
            app.VerticalRExpandedHTML.Data = struct( ...
                'bgColor',      '#ffa0a0', ...
                'textColor',    '#ffffff', ...
                'borderColor',  '#ffa0a0', ...
                'borderWidth',  '0',     ...
                'borderRadius', '14px',    ...
                'label',        'R Vertical +' ...
                );
            app.HorizontalRExpandedHTML.Data = struct( ...
                'bgColor',      '#ffa0a0', ...
                'textColor',    '#ffffff', ...
                'borderColor',  '#ffa0a0', ...
                'borderWidth',  '0',     ...
                'borderRadius', '14px',    ...
                'label',        'R Horizontal +' ...
                );
            app.HorizontalLExpandedHTML.Data = struct( ...
                'bgColor',      '#9bbeff', ...
                'textColor',    '#ffffff', ...
                'borderColor',  '#9bbeff', ...
                'borderWidth',  '0',     ...
                'borderRadius', '14px',    ...
                'label',        'L Horizontal +' ...
                );
            app.VerticalLExpandedHTML.Data = struct( ...
                'bgColor',      '#9bbeff', ...
                'textColor',    '#ffffff', ...
                'borderColor',  '#9bbeff', ...
                'borderWidth',  '0',     ...
                'borderRadius', '14px',    ...
                'label',        'L Vertical +' ...
                );
            app.ResultadosAnalisisHTML.Data = struct( ...
                'bgColor',      '#e3ffe7', ...
                'textColor',    '#4b0082', ...
                'borderColor',  '#bb99ff', ...
                'borderWidth',  '2px',     ...
                'borderRadius', '20px',    ...
                'label',        'Resultados Analisis' ...
                );
            app.TomarMedidasHTML.Data = struct( ...
                'bgColor',      '#afeeee', ...
                'textColor',    '#6a06b3', ...
                'borderColor',  '#9370db', ...
                'borderWidth',  '2px',     ...
                'borderRadius', '18px',    ...
                'label',        'Tomar Medidas' ...
                );
            app.ConectarGafasHTML.Data = struct( ...
                'bgColor',      '#a0c4ff', ...
                'textColor',    '#4b0082', ...
                'borderColor',  '#a0c4ff', ...
                'borderWidth',  '0',     ...
                'borderRadius', '26px',    ...
                'label',        'Conectar Gafas Horus' ...
                );
            app.AnalisisEventosHTML.Data = struct( ...
                'bgColor',      '#cce0ff', ...
                'textColor',    '#4b0082', ...
                'borderColor',  '#cce0ff', ...
                'borderWidth',  '0',     ...
                'borderRadius', '16px',    ...
                'label',        'Analisis Eventos' ...
                );
            app.VerAnalisisPreviosHTML.Data = struct( ...
                'bgColor',      '#a0c4ff', ...
                'textColor',    '#4b0082', ...
                'borderColor',  '#a0c4ff', ...
                'borderWidth',  '0',     ...
                'borderRadius', '26px',    ...
                'label',        'Ver Analisis Previos' ...
                );
            app.EventsTable.ColumnName = { ...
    'Inicio (s)', ...
    'Fin (s)', ...
    'Duración (s)', ...
    'Amplitud (mV)', ...
    'Velocidad máx (mV/s)', ...
    'Dirección'};
            ChangeWindowTo(app,app.MainScreen);
            app.AccederHTML.Visible = 'on';
        end

        % Value changed function: ShowHiddenWifisCheckBox
        function ShowHiddenWifisClicked(app, event)
            value = app.ShowHiddenWifisCheckBox.Value;
            if value == 1
                ReloadListWifis(app,app.AvailableWifis);
            else
                ReloadListWifis(app,app.DevicesWifis);
            end
        end

        % Button pushed function: StartBT
        function StartClicked(app, event)
            if strcmp(app.StartBT.Text, 'START')
                TomarMedidasClicked(app);
                DataAcquisition(app);
            else
                StopAcquisition(app, 'manual');
            end
        end

        % Event received function: DevicesListHTML
        function DevicesListClicked(app, event)
            eventData = event.HTMLEventData;
            value = eventData.name;
            ChangeWifiStatus(app,'conectando');
            app.DevicesListHTML.Data = struct('action', 'setState', 'name', value, 'state', 'connecting');
            ConectingWifi = uiprogressdlg(app.UIFigure, ...
                'Title', 'Conectando...', 'Message', 'Conectandose al Dispositivo', 'Indeterminate', 'on');
            drawnow
            for p = app.PasswordOptions
                ConectingWifi.Message = 'Probando Contraseña: ' + p;
                createWifiProfile(app,value,p);

                system("netsh wlan connect name=""" + value + """");

                timeout = 8;
                t0 = tic;

                while toc(t0) < timeout

                    [~,out] = system('netsh wlan show interfaces');

                    % comprobar estado
                    stateLine = regexp(out,'Estado\s*:\s*([^\r\n]*)','tokens','once');

                    % comprobar SSID
                    ssidLine = regexp(out,'SSID\s*:\s*([^\r\n]*)','tokens','once');

                    if ~isempty(stateLine) && ~isempty(ssidLine)

                        state = strtrim(lower(stateLine{1}));
                        currentSSID = strtrim(ssidLine{1});
                        disp("Estado: " + state)
                        disp("SSID: " + currentSSID)

                        if (strcmp(state,"conectado") || strcmp(state,"connected")) && strcmp(currentSSID,value)

                            disp("Conectado con: " + p);
                            app.ConnectedDevice = true;
                            close(ConectingWifi);
                            uialert(app.UIFigure,'Dispositivo Conectado','Conexion Hecha','Icon','success');
                            app.TomarMedidasHTML.Visible = 'on';
                            ChangeWifiStatus(app,'conectado');
                            app.LogoHorusHTML.Data = struct('color', '#95F18B');
                            app.DevicesListHTML.Data = struct('action', 'setState', 'name', value, 'state', 'connected');
                            return

                        end

                    end

                    pause(0.3)

                end
                ChangeWifiStatus(app,'error');
                app.LogoHorusHTML.Data = struct('color', '#FF3333');
            end

        end

        % Event received function: BuscarDevicesHTML
        function BuscarDevicesClicked(app, event)
            if strcmp(event.HTMLEventName, "ButtonClicked")
                RefreshWifis(app);
            end
        end

        % Event received function: AccederHTML, AnalisisEventosHTML, 
        % ...and 9 other components
        function ButtonClicked(app, event)
            eventName = event.HTMLEventName;
            eventData = event.HTMLEventData;
            if strcmp(eventName, 'ButtonClicked')
                if strcmp(eventData, 'Tomar Medidas')
                    TomarMedidasClicked(app);
                elseif strcmp(eventData, 'Acceder')
                    AccederClicked(app);
                elseif strcmp(eventData, 'Analizar Medidas')
                    AnalizarMedidasClicked(app);
                elseif strcmp(eventData, 'Resultados Analisis')
                    ResultadosAnalisisClicked(app);
                elseif strcmp(eventData, 'Conectar Gafas Horus')
                    ConectarGafasClicked(app);
                elseif strcmp(eventData, 'Ver Analisis Previos')
                    VerAnalisisPreviosClicked(app);
                elseif strcmp(eventData, 'Analisis Eventos')
                    AnalisisEventosClicked(app);
                elseif strcmp(eventData, 'R Horizontal +')
                    ExpandSignalView(app,eventData);
                elseif strcmp(eventData, 'R Vertical +')
                    ExpandSignalView(app,eventData);
                elseif strcmp(eventData, 'L Horizontal +')
                    ExpandSignalView(app,eventData);
                elseif strcmp(eventData, 'L Vertical +')
                    ExpandSignalView(app,eventData);
                end
            end
        end

        % Event received function: BackAnalisisHTML, BackChooseActionHTML, 
        % ...and 4 other components
        function BackButtonClicked(app, event)
            eventName = event.HTMLEventName;
            eventData = event.HTMLEventData.id;
            disp(eventData);
            if strcmp(eventName, 'buttonClicked')
                if strcmp(eventData, 'ChooseActionScreen')
                    BackChooseActionClicked(app);
                elseif strcmp(eventData, 'ConectScreen')
                    BackConectClicked(app);
                elseif strcmp(eventData, 'GraficasScreen')
                    BackGraficasClicked(app);
                elseif strcmp(eventData, 'ExpandedViewScreen')
                    BackExpandedViewClicked(app);
                elseif strcmp(eventData, 'AnalisisScreen')
                    BackAnalisisClicked(app);
                elseif strcmp(eventData, 'EventsAnalisisScreen')
                    BackEventsAnalisisClicked(app);
                end
            end
        end

        % Close request function: UIFigure
        function closeApp(app, event)
            if ~isempty(app.SHEOL)
                selection = uiconfirm(app.UIFigure, ...
                    'You have unsaved signals. Do you want to save before closing?', ...
                    'Save before closing?', ...
                    'Options',       {'Save and close', 'Close without saving', 'Cancel'}, ...
                    'DefaultOption', 'Save and close', ...
                    'CancelOption',  'Cancel', ...
                    'Icon',          'question');

                switch selection
                    case 'Save and close'
                        saved = saveSignals(app);
                        if ~saved
                            return  % user cancelled file dialog, abort close
                        end
                    case 'Cancel'
                        return
                    case 'Close without saving'
                        % continue to close
                end
            end

            if ~isempty(app.TimerGraficar) && isvalid(app.TimerGraficar)
                stop(app.TimerGraficar);
                delete(app.TimerGraficar);
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeApp, true);
            app.ChooseActionScreen = uipanel(app.UIFigure);
            app.ChooseActionGrid = uigridlayout(app.ChooseActionScreen);
            app.Image = uiimage(app.ChooseActionGrid);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'GAFAS-HORUS-LOGO.svg');
            app.Image2 = uiimage(app.ChooseActionGrid);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'patient.svg');
            app.VerAnalisisPreviosHTML = uihtml(app.ChooseActionGrid);
            app.VerAnalisisPreviosHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.ConectarGafasHTML = uihtml(app.ChooseActionGrid);
            app.ConectarGafasHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.BackChooseActionHTML = uihtml(app.ChooseActionGrid);
            app.BackChooseActionHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @BackButtonClicked, true);
            app.ConectScreen = uipanel(app.UIFigure);
            app.ConectGrid = uigridlayout(app.ConectScreen);
            app.ShowHiddenWifisCheckBox = uicheckbox(app.ConectGrid);
            app.ShowHiddenWifisCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowHiddenWifisClicked, true);
            app.ShowHiddenWifisCheckBox.Text = 'Enseñar Redes Escondidas';
            app.WifiStatusHTML = uihtml(app.ConectGrid);
            app.DevicesListHTML = uihtml(app.ConectGrid);
            app.DevicesListHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @DevicesListClicked, true);
            app.LogoHorusHTML = uihtml(app.ConectGrid);
            app.BuscarDevicesHTML = uihtml(app.ConectGrid);
            app.BuscarDevicesHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @BuscarDevicesClicked, true);
            app.TomarMedidasHTML = uihtml(app.ConectGrid);
            app.TomarMedidasHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.BackConectHTML = uihtml(app.ConectGrid);
            app.BackConectHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @BackButtonClicked, true);
            app.MainScreen = uipanel(app.UIFigure);
            app.MainGrid = uigridlayout(app.MainScreen);
            app.SignInLBL = uilabel(app.MainGrid);
            app.SignInLBL.Text = 'Inicia Sesión';
            app.LOGOSEPA = uiimage(app.MainGrid);
            app.LOGOSEPA.ImageSource = fullfile(pathToMLAPP, 'LOGO-SEPA-EXPANDED.png');
            app.SignInStateLBL = uilabel(app.MainGrid);
            app.SignInStateLBL.Text = 'Sesion Iniciada Correctamente';
            app.Hyperlink = uihyperlink(app.MainGrid);
            app.Hyperlink.Text = 'Crear Cuenta';
            app.AccederHTML = uihtml(app.MainGrid);
            app.AccederHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.ConectionCableCheckBox = uicheckbox(app.MainGrid);
            app.ConectionCableCheckBox.Text = 'Conexión por cable';
            app.UserNameHTML = uihtml(app.MainGrid);
            app.PasswordHTML = uihtml(app.MainGrid);
            app.ExpandedViewScreen = uipanel(app.UIFigure);
            app.ExpandedViewGrid = uigridlayout(app.ExpandedViewScreen);
            app.ExpandedPlot = uiaxes(app.ExpandedViewGrid);
            title(app.ExpandedPlot, 'Signal View')
            xlabel(app.ExpandedPlot, 'X')
            ylabel(app.ExpandedPlot, 'Y')
            zlabel(app.ExpandedPlot, 'Z')
            colormap(app.ExpandedPlot, 'winter')
            app.BackExpandedViewHTML = uihtml(app.ExpandedViewGrid);
            app.BackExpandedViewHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @BackButtonClicked, true);
            app.AnalisisSignalsScreen = uipanel(app.UIFigure);
            app.AnalisisSignalsGrid = uigridlayout(app.AnalisisSignalsScreen);
            app.LVerticalAxis = uiaxes(app.AnalisisSignalsGrid);
            title(app.LVerticalAxis, 'L Vertical')
            zlabel(app.LVerticalAxis, 'Z')
            app.LHorizontalAxis = uiaxes(app.AnalisisSignalsGrid);
            title(app.LHorizontalAxis, 'L Horizontal')
            zlabel(app.LHorizontalAxis, 'Z')
            app.RVerticalAxis = uiaxes(app.AnalisisSignalsGrid);
            title(app.RVerticalAxis, 'R Vertical')
            zlabel(app.RVerticalAxis, 'Z')
            app.RHorizontalAxis = uiaxes(app.AnalisisSignalsGrid);
            title(app.RHorizontalAxis, 'R Horizontal')
            zlabel(app.RHorizontalAxis, 'Z')
            app.EYER2 = uiimage(app.AnalisisSignalsGrid);
            app.EYER2.ImageSource = fullfile(pathToMLAPP, 'EYE-HORUS-L.svg');
            app.EYEL2 = uiimage(app.AnalisisSignalsGrid);
            app.EYEL2.ImageSource = fullfile(pathToMLAPP, 'EYE-HORUS-R.svg');
            app.HorizontalLExpandedHTML = uihtml(app.AnalisisSignalsGrid);
            app.HorizontalLExpandedHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.HorizontalRExpandedHTML = uihtml(app.AnalisisSignalsGrid);
            app.HorizontalRExpandedHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.VerticalLExpandedHTML = uihtml(app.AnalisisSignalsGrid);
            app.VerticalLExpandedHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.VerticalRExpandedHTML = uihtml(app.AnalisisSignalsGrid);
            app.VerticalRExpandedHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.ResultadosAnalisisHTML = uihtml(app.AnalisisSignalsGrid);
            app.ResultadosAnalisisHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.EventsAnalisisScreen = uipanel(app.UIFigure);
            app.EventsAnalisisGrid = uigridlayout(app.EventsAnalisisScreen);
            app.EventsAnalisisLBL = uilabel(app.EventsAnalisisGrid);
            app.EventsAnalisisLBL.Text = 'Analisis de Eventos Oculares';
            app.EventsTable = uitable(app.EventsAnalisisGrid);
            app.EventsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.EventsTable.RowName = {};
            app.BackEventsAnalisisHTML = uihtml(app.EventsAnalisisGrid);
            app.BackEventsAnalisisHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @BackButtonClicked, true);
            app.GraficasScreen = uipanel(app.UIFigure);
            app.GraficasGrid = uigridlayout(app.GraficasScreen);
            app.VEOR = uiaxes(app.GraficasGrid);
            title(app.VEOR, 'VEOR')
            zlabel(app.VEOR, 'Z')
            app.HEOL = uiaxes(app.GraficasGrid);
            title(app.HEOL, 'HEOL')
            zlabel(app.HEOL, 'Z')
            app.HEOR = uiaxes(app.GraficasGrid);
            title(app.HEOR, 'HEOR')
            zlabel(app.HEOR, 'Z')
            app.VEOL = uiaxes(app.GraficasGrid);
            title(app.VEOL, 'VEOL')
            zlabel(app.VEOL, 'Z')
            app.ConectedLampGrid = uigridlayout(app.GraficasGrid);
            app.ConectedLBL = uilabel(app.ConectedLampGrid);
            app.ConectedLBL.Text = 'Conectado';
            app.ConectedLamp = uilamp(app.ConectedLampGrid);
            app.StartBT = uibutton(app.GraficasGrid, 'push');
            app.StartBT.ButtonPushedFcn = createCallbackFcn(app, @StartClicked, true);
            app.StartBT.Icon = 'play.svg';
            app.StartBT.Text = 'START';
            app.EYER = uiimage(app.GraficasGrid);
            app.EYER.ImageSource = fullfile(pathToMLAPP, 'EYE-HORUS-L.svg');
            app.EYEL = uiimage(app.GraficasGrid);
            app.EYEL.ImageSource = fullfile(pathToMLAPP, 'EYE-HORUS-R.svg');
            app.AnalizarMedidasHTML = uihtml(app.GraficasGrid);
            app.AnalizarMedidasHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            app.BackGraficasHTML = uihtml(app.GraficasGrid);
            app.BackGraficasHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @BackButtonClicked, true);
            app.FSLabel = uilabel(app.GraficasGrid);
            app.FSLabel.Text = 'Label3';
            app.AnalisisScreen = uipanel(app.UIFigure);
            app.AnalisisGrid = uigridlayout(app.AnalisisScreen);
            app.UITableResultadosIzquierda = uitable(app.AnalisisGrid);
            app.UITableResultadosIzquierda.ColumnName = {''; 'Conteo'; 'Leves'; 'Fuertes'};
            app.UITableResultadosIzquierda.RowName = {};
            app.ANLISISPACIENTELabel = uilabel(app.AnalisisGrid);
            app.ANLISISPACIENTELabel.Text = 'ANÁLISIS PACIENTE';
            app.Image_2 = uiimage(app.AnalisisGrid);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'PERSON-ICON.png');
            app.DATOSDELPACIENTELabel = uilabel(app.AnalisisGrid);
            app.DATOSDELPACIENTELabel.Text = 'DATOS DEL PACIENTE';
            app.FATIGAname = uilabel(app.AnalisisGrid);
            app.FATIGAname.Text = 'FATIGA:';
            app.PatientInfoLabel = uilabel(app.AnalisisGrid);
            app.FatigaLabel = uilabel(app.AnalisisGrid);
            app.FatigaLabel.Text = 'Label2';
            app.UITableResultadosDerecha = uitable(app.AnalisisGrid);
            app.UITableResultadosDerecha.ColumnName = {''; 'Conteo'; 'Leves'; 'Fuertes'};
            app.UITableResultadosDerecha.RowName = {};
            app.miradasIzquierdaLbl = uilabel(app.AnalisisGrid);
            app.miradasIzquierdaLbl.Text = 'Miradas Izquierda:';
            app.miradasDerechaLbl = uilabel(app.AnalisisGrid);
            app.miradasDerechaLbl.Text = 'Miradas Derecha:';
            app.ExportButton = uibutton(app.AnalisisGrid, 'push');
            app.ExportButton.Text = 'Export to .tsv';
            app.ObservacionesNamelbl = uilabel(app.AnalisisGrid);
            app.ObservacionesNamelbl.Text = 'OBSERVACIONES:';
            app.ObservacionesLBL = uilabel(app.AnalisisGrid);
            app.ObservacionesLBL.Text = 'Label2';
            app.BackAnalisisHTML = uihtml(app.AnalisisGrid);
            app.BackAnalisisHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @BackButtonClicked, true);
            app.AnalisisEventosHTML = uihtml(app.AnalisisGrid);
            app.AnalisisEventosHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ButtonClicked, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HORUS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end